classdef QuantumMemristorDynamicFinal3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantumMemristorUI              matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        QuantumCircuitDesignTab         matlab.ui.container.Tab
        ViewgateselectionasSwitch       matlab.ui.control.RockerSwitch
        ViewgateselectionasSwitchLabel  matlab.ui.control.Label
        NoofComputationalStepsSliderLabel  matlab.ui.control.Label
        NoofQubitsSliderLabel           matlab.ui.control.Label
        NoofComputationalSteps          matlab.ui.control.Slider
        NoofQubits                      matlab.ui.control.Slider
        GENERATE                        matlab.ui.control.Button
        MemristorCrossbarTab            matlab.ui.container.Tab
        Crossbar                        matlab.ui.control.UIAxes
        WriteVoltageFiguresTab          matlab.ui.container.Tab
        SelectComputationalSteptoViewDropDown  matlab.ui.control.DropDown
        SelectComputationalSteptoViewDropDownLabel  matlab.ui.control.Label
        SelectorVoltagesPlot            matlab.ui.control.UIAxes
        WriteVoltagesPlot               matlab.ui.control.UIAxes
        QuantumComputationsTab          matlab.ui.container.Tab
        RUN                             matlab.ui.control.Button
        PLOT                            matlab.ui.control.UIAxes
        MemristorSettingsTab            matlab.ui.container.Tab
        SimulationTimeStepEditField     matlab.ui.control.NumericEditField
        SimulationTimeStepEditFieldLabel  matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        T0EditField                     matlab.ui.control.NumericEditField
        T0EditFieldLabel                matlab.ui.control.Label
        Tau_thEditField                 matlab.ui.control.NumericEditField
        Tau_thEditFieldLabel            matlab.ui.control.Label
        CthEditField                    matlab.ui.control.NumericEditField
        CthEditFieldLabel               matlab.ui.control.Label
        betaEditField                   matlab.ui.control.NumericEditField
        betaEditFieldLabel              matlab.ui.control.Label
        g1EditField                     matlab.ui.control.NumericEditField
        g1EditFieldLabel                matlab.ui.control.Label
        gamma0EditField                 matlab.ui.control.NumericEditField
        gamma0EditFieldLabel            matlab.ui.control.Label
        Ve10EditField                   matlab.ui.control.NumericEditField
        Ve10EditFieldLabel              matlab.ui.control.Label
        V0EditField                     matlab.ui.control.NumericEditField
        V0EditFieldLabel                matlab.ui.control.Label
        g0EditField                     matlab.ui.control.NumericEditField
        g0EditFieldLabel                matlab.ui.control.Label
        I0EditField                     matlab.ui.control.NumericEditField
        I0EditFieldLabel                matlab.ui.control.Label
        gap_maxEditField                matlab.ui.control.NumericEditField
        gap_maxEditFieldLabel           matlab.ui.control.Label
        gap_minEditField                matlab.ui.control.NumericEditField
        gap_minEditFieldLabel           matlab.ui.control.Label
        eLEditField                     matlab.ui.control.NumericEditField
        eLEditFieldLabel                matlab.ui.control.Label
        EarEditField                    matlab.ui.control.NumericEditField
        EarEditFieldLabel               matlab.ui.control.Label
        EagEditField                    matlab.ui.control.NumericEditField
        EagEditFieldLabel               matlab.ui.control.Label
        a0EditField                     matlab.ui.control.NumericEditField
        a0EditFieldLabel                matlab.ui.control.Label
        qEditField                      matlab.ui.control.NumericEditField
        qEditFieldLabel                 matlab.ui.control.Label
        PulseParametersLabel            matlab.ui.control.Label
        ModelParametersLabel            matlab.ui.control.Label
        kbEditField                     matlab.ui.control.NumericEditField
        kbEditFieldLabel                matlab.ui.control.Label
    end

    properties (Access = public)
     g;
     R;
     Output_states=0;
     coefficients;
     Wdots=0;
     V_write=0;
     VT_plot=0;
     time_plot=0;
    end
    
    properties (Access = private)
        maxQB = 1;
        maxCS = 1;

        Steps = 12;
        Qubits = 6;
        QuBitStateW = 100;
        maxH = 550;
        
        MyLabels
        MyDropDown
        MyKnob
        MySwitch
        MySwitchLabelSim
        MySwitchLabelRes
        TheLabelSim
        TheLabelRes
        TheLabelResSwitch
        CalcLabel
        CalcBG
        
        I = [1 0; 0 1];
        H = 2^(-.5) .* [1 1; 1 -1];
        X = [0 1; 1 0];
        Z = [1 0; 0 -1];
        CZ = [1 0 0 0; 0 1 0 0; 0 0 1 0; 0 0 0 -1];
        CNOT = [1 0 0 0; 0 1 0 0; 0 0 0 1; 0 0 1 0];
        CCNOT = [1 0 0 0 0 0 0 0; 0 1 0 0 0 0 0 0; 0 0 1 0 0 0 0 0; 0 0 0 1 0 0 0 0; 0 0 0 0 1 0 0 0; 0 0 0 0 0 1 0 0; 0 0 0 0 0 0 0 1; 0 0 0 0 0 0 1 0];
        SWAP = [1 0 0 0; 0 0 1 0; 0 1 0 0; 0 0 0 1];
        CSWAP = [1 0 0 0 0 0 0 0; 0 1 0 0 0 0 0 0; 0 0 1 0 0 0 0 0; 0 0 0 1 0 0 0 0; 0 0 0 0 1 0 0 0; 0 0 0 0 0 0 1 0; 0 0 0 0 0 1 0 0; 0 0 0 0 0 0 0 1];
    end
    
    methods (Access = public)
        function create(app)
            for i = 1:app.Qubits
                QubitsMajor(i) = i;
            end
            
            app.NoofQubits.Limits = [1 app.Qubits];
            app.NoofQubits.MajorTicks = QubitsMajor;
            
            for j = 1:app.Steps
                StepsMajor(j) = j;
            end
            
            app.NoofComputationalSteps.Limits = [1 app.Steps];
            app.NoofComputationalSteps.MajorTicks = StepsMajor;
                        
            maxW = 1260 - app.QuBitStateW;
            KnobA = 0.95*min(maxW/(app.Steps+1),app.maxH/(app.Qubits+1));
                                
            app.TheLabelSim = uilabel(app.QuantumCircuitDesignTab);
            app.TheLabelSim.HorizontalAlignment = 'center';
            app.TheLabelSim.Position = [10 app.maxH app.QuBitStateW 22];
            app.TheLabelSim.Text = 'QUBIT No.';
            app.TheLabelSim.FontWeight = 'bold';
                                
            app.TheLabelRes = uilabel(app.QuantumComputationsTab);
            app.TheLabelRes.HorizontalAlignment = 'center';
            app.TheLabelRes.Position = [10 app.maxH app.QuBitStateW 22];
            app.TheLabelRes.Text = 'QUBIT No.';
            app.TheLabelRes.FontWeight = 'bold';
                                
            app.TheLabelResSwitch = uilabel(app.QuantumComputationsTab);
            app.TheLabelResSwitch.HorizontalAlignment = 'center';
            app.TheLabelResSwitch.Position = [app.QuBitStateW+20 app.maxH app.QuBitStateW 22];
            app.TheLabelResSwitch.Text = 'VALUE';
            app.TheLabelResSwitch.FontWeight = 'bold';
            
            for i = 1:app.Qubits
                app.MySwitchLabelSim{i} = uilabel(app.QuantumCircuitDesignTab);
                app.MySwitchLabelSim{i}.HorizontalAlignment = 'center';
                app.MySwitchLabelSim{i}.Position = [10 app.maxH-(i-0.63)*app.maxH/app.Qubits-app.maxH/(app.Qubits+2)/4 app.QuBitStateW app.maxH/(app.Qubits+2)/2];
                app.MySwitchLabelSim{i}.Text = int2str(i);
                app.MySwitchLabelSim{i}.FontWeight = 'bold';
                app.MySwitchLabelSim{i}.FontSize = 33;
                
                app.MySwitchLabelRes{i} = uilabel(app.QuantumComputationsTab);
                app.MySwitchLabelRes{i}.HorizontalAlignment = 'center';
                app.MySwitchLabelRes{i}.Position = [10 app.maxH-(i-0.63)*app.maxH/app.Qubits-app.maxH/(app.Qubits+2)/4 app.QuBitStateW app.maxH/(app.Qubits+2)/2];
                app.MySwitchLabelRes{i}.Text = int2str(i);
                app.MySwitchLabelRes{i}.FontWeight = 'bold';
                app.MySwitchLabelRes{i}.FontSize = 33;
            
                % Create Switch
                app.MySwitch{i} = uiswitch(app.QuantumComputationsTab, 'slider');
                app.MySwitch{i}.Items = {'|0>', '|1>'};
                app.MySwitch{i}.FontSize = 14;
                app.MySwitch{i}.FontWeight = 'bold';
                app.MySwitch{i}.Orientation = 'horizontal';
                app.MySwitch{i}.Position = [app.QuBitStateW+50 app.maxH-(i-0.63)*app.maxH/app.Qubits-app.maxH/(app.Qubits+2)/4 app.QuBitStateW-70 app.maxH/(app.Qubits+2)/2];
                app.MySwitch{i}.Value = '|1>';
                
                for j = 1:app.Steps
                    
                    if i==1
                        % Create DropDownLabel
                        app.MyLabels{j} = uilabel(app.QuantumCircuitDesignTab);
                        app.MyLabels{j}.HorizontalAlignment = 'center';
                        app.MyLabels{j}.Position = [app.QuBitStateW+(j-1)*maxW/app.Steps app.maxH maxW/app.Steps 22];
                        app.MyLabels{j}.Text = ['STEP ' num2str(j)];
                        app.MyLabels{j}.FontWeight = 'bold';
                    end
                    
                    app.MyKnob{i,j} = uidropdown(app.QuantumCircuitDesignTab);
                    
                    if i < 2
                        app.MyKnob{i,j}.Items = {'I', 'H', 'X', 'Z'};
                    elseif i < 3
                        app.MyKnob{i,j}.Items = {'I', 'H', 'X', 'Z', 'CZ', 'CNOT', 'SWAP'};
                    else
                        app.MyKnob{i,j}.Items = {'I', 'H', 'X', 'Z', 'CZ', 'CNOT', 'CCNOT', 'SWAP', 'CSWAP'};
                    end
                    
                    app.MyKnob{i,j}.Position = [app.QuBitStateW+(j-0.5)*maxW/app.Steps-KnobA/2 app.maxH-(i-0.5)*app.maxH/app.Qubits-KnobA/2 KnobA KnobA];
                    app.MyKnob{i,j}.Value = 'I';
                    app.MyKnob{i,j}.FontSize = 10;
                    app.MyKnob{i,j}.ValueChangedFcn = createCallbackFcn(app, @MyKnobValueChanged, true);
                    app.MyKnob{i,j}.UserData = [i j];
                end
            end
            
            app.CalcBG = uilabel(app.QuantumMemristorUI);
            app.CalcBG.Position = [0 0 1280 720];
            app.CalcBG.BackgroundColor = [0 0 0];
            app.CalcBG.Enable = 'off';
            
            app.CalcLabel = uilabel(app.QuantumMemristorUI);
            app.CalcLabel.HorizontalAlignment = 'center';
            app.CalcLabel.VerticalAlignment = 'center';
            app.CalcLabel.Position = [0 0 1280 720];
            app.CalcLabel.Text = 'PLEASE WAIT...';
            app.CalcLabel.FontWeight = 'bold';
        end
        
        function EnableQBs(app)   
            for i = 1:app.Qubits
                if i <= app.maxQB
                    app.MySwitch{i}.Enable = 'on';
                    app.MySwitchLabelSim{i}.Enable = 'on';
                    app.MySwitchLabelRes{i}.Enable = 'on';
                else
                    app.MySwitch{i}.Enable = 'off';
                    app.MySwitchLabelSim{i}.Enable = 'off';
                    app.MySwitchLabelRes{i}.Enable = 'off';
                end
            end
            
            app.RefreshPlot();
            app.EnableCSs();
        end
        
        function RefreshPlot(app)
            for i = 1:2^app.maxQB
                tickx(i) = i;
                x = num2str(flip(de2bi(i-1,app.maxQB)));
                x = "|" + x(~isspace(x)) + ">";
                ticklabel(i) = x;
            end
            
            app.PLOT.XLim = [0 2^app.maxQB+1];
            app.PLOT.XTick = tickx;
            app.PLOT.XTickLabel = ticklabel';
            app.PLOT.YLim = [-1 1];
        end
        
        function EnableCSs(app)
            for i = app.Qubits:-1:1
                for j = app.Steps:-1:1
                    if i <= app.maxQB && j <= app.maxCS
                        app.MyKnobCheckPrevious(i, j);
                    else
                        app.MyKnob{i,j}.Enable = 'off';
                        app.MyKnob{i,j}.Value = 'I';
                    end
                end
            end
            
            for i = 1:app.maxCS
                steps(i) = "Step " + num2str(i);
            end
            
            app.SelectComputationalSteptoViewDropDown.Items = steps;
            app.SelectComputationalSteptoViewDropDown.Value = 'Step 1';
            
            app.EnableGenerate();
        end
        
        function EnableGenerate(app)
            app.GENERATE.Enable = 'on';
            app.RUN.Enable = 'off';
            
            cla(app.Crossbar);
            cla(app.WriteVoltagesPlot);
            cla(app.SelectorVoltagesPlot);
            cla(app.PLOT);
        end
        
        function EnableRun(app)
            app.GENERATE.Enable = 'off';
            app.RUN.Enable = 'on';
        end
        
        function EnableCalc(app)
            app.CalcBG.Visible = 'on';
            app.CalcLabel.Visible = 'on';
        end
        
        function DisableCalc(app)
            app.CalcBG.Visible = 'off';
            app.CalcLabel.Visible = 'off';
        end
        
        function MyKnobValueChanged(app, event)
            i = event.Source.UserData(1);
            j = event.Source.UserData(2);
            
            if (strcmp(app.MyKnob{i,j}.Value, 'CZ') || strcmp(app.MyKnob{i,j}.Value, 'CNOT') || strcmp(app.MyKnob{i,j}.Value, 'CCNOT') || strcmp(app.MyKnob{i,j}.Value, 'SWAP') || strcmp(app.MyKnob{i,j}.Value, 'CSWAP'))
                app.MyKnobCheckPrevious(i-1, j);
            end
            
            app.EnableGenerate();
        end
        
        function MyKnobCheckPrevious(app, i, j)
            if i > 0
                if (i < app.Qubits && (strcmp(app.MyKnob{i+1,j}.Value, 'CZ') || strcmp(app.MyKnob{i+1,j}.Value, 'CNOT') || strcmp(app.MyKnob{i+1,j}.Value, 'CCNOT') || strcmp(app.MyKnob{i+1,j}.Value, 'SWAP') || strcmp(app.MyKnob{i+1,j}.Value, 'CSWAP')))
                    app.MyKnob{i,j}.Enable = 'off';
                    app.MyKnob{i,j}.Value = 'I';
                elseif (i+1 < app.Qubits && (strcmp(app.MyKnob{i+2,j}.Value, 'CCNOT') || strcmp(app.MyKnob{i+2,j}.Value, 'CSWAP')))
                    app.MyKnob{i,j}.Enable = 'off';
                    app.MyKnob{i,j}.Value = 'I';
                else
                    app.MyKnob{i,j}.Enable = 'on';
                end
                
                app.MyKnobCheckPrevious(i-1, j);
            end
        end
        
        function GenerateButton(app)
            for j = 1:app.maxCS
                switch app.MyKnob{1,j}.Value
                    case 'I'
                        step = app.I;
                    case 'H'
                        step = app.H;
                    case 'X'
                        step = app.X;
                    case 'Z'
                        step = app.Z;
                end
                
                for i = 2:app.maxQB
                    switch app.MyKnob{i,j}.Value
                        case 'I'
                            step = kron(step, app.I);
                        case 'H'
                            step = kron(step, app.H);
                        case 'X'
                            step = kron(step, app.X);
                        case 'Z'
                            step = kron(step, app.Z);
                        case 'CZ'
                            step = step(1:size(app.I,1):end,1:size(app.I,2):end)./app.I(1);
                            step = kron(step, app.CZ);
                        case 'CNOT'
                            step = step(1:size(app.I,1):end,1:size(app.I,2):end)./app.I(1);
                            step = kron(step, app.CNOT);
                        case 'CCNOT'
                            step = step(1:size(app.I,1):end,1:size(app.I,2):end)./app.I(1);
                            step = step(1:size(app.I,1):end,1:size(app.I,2):end)./app.I(1);
                            step = kron(step, app.CCNOT);
                        case 'SWAP'
                            step = step(1:size(app.I,1):end,1:size(app.I,2):end)./app.I(1);
                            step = kron(step, app.SWAP);
                        case 'CSWAP'
                            step = step(1:size(app.I,1):end,1:size(app.I,2):end)./app.I(1);
                            step = step(1:size(app.I,1):end,1:size(app.I,2):end)./app.I(1);
                            step = kron(step, app.CSWAP);
                    end
                end

                weight(j) = max(step(:));
                GigaGate(:,:,j) = step ./ max(step(:));
            end
                
            disp(weight);
            disp(GigaGate);
            disp(app.GetParam());
            
            app.coefficients = weight;

            [app.g, app.R, app.V_write, app.VT_plot, app.time_plot] = Crossbar_Generation(GigaGate,app.GetParam());
            disp_crossbar(app);
            disp_write(app);
            app.DisableCalc();
        end

        function RunButton(app)
            if str2num(app.MySwitch{1}.Value(2))
                final = [0; 1];
            else
                final = [1; 0];
            end
            
            for i = 2:app.maxQB
                if str2num(app.MySwitch{i}.Value(2))
                    final = kron(final, [0; 1]);
                else
                    final = kron(final, [1; 0]);
                end
            end
            
            disp(final);
            disp(app.GetParam());
            
            app.Output_states = Crossbar_Computing(app.g, final, app.coefficients,app.GetParam());
            bar(app.PLOT, app.Output_states);
            app.RefreshPlot();
            app.DisableCalc();
        end
        
        function GateView(app)
            maxW = 1260 - app.QuBitStateW;
            
            for i = 1:app.Qubits
                for j = 1:app.Steps
                    value = app.MyKnob{i,j}.Value;
                    delete(app.MyKnob{i,j});
                    
                    switch app.ViewgateselectionasSwitch.Value
                        case 'Dropdown'
                            KnobA = 0.95*min(maxW/(app.Steps+1),app.maxH/(app.Qubits+1));
                            app.MyKnob{i,j} = uidropdown(app.QuantumCircuitDesignTab);
                        case 'Knob'
                            KnobA = 0.45*min(maxW/(app.Steps+1),app.maxH/(app.Qubits+1));
                            app.MyKnob{i,j} = uiknob(app.QuantumCircuitDesignTab, 'discrete');
                    end
                    
                    if i < 2
                        app.MyKnob{i,j}.Items = {'I', 'H', 'X', 'Z'};
                    elseif i < 3
                        app.MyKnob{i,j}.Items = {'I', 'H', 'X', 'Z', 'CZ', 'CNOT', 'SWAP'};
                    else
                        app.MyKnob{i,j}.Items = {'I', 'H', 'X', 'Z', 'CZ', 'CNOT', 'CCNOT', 'SWAP', 'CSWAP'};
                    end
                    
                    app.MyKnob{i,j}.Position = [app.QuBitStateW+(j-0.5)*maxW/app.Steps-KnobA/2 app.maxH-(i-0.5)*app.maxH/app.Qubits-KnobA/2 KnobA KnobA];
                    app.MyKnob{i,j}.Value = value;
                    app.MyKnob{i,j}.FontSize = 10;
                    app.MyKnob{i,j}.ValueChangedFcn = createCallbackFcn(app, @MyKnobValueChanged, true);
                    app.MyKnob{i,j}.UserData = [i j];
                end
            end
            
            delete(app.CalcBG);
            app.CalcBG = uilabel(app.QuantumMemristorUI);
            app.CalcBG.Position = [0 0 1280 720];
            app.CalcBG.BackgroundColor = [0 0 0];
            app.CalcBG.Enable = 'off';
            
            delete(app.CalcLabel);
            app.CalcLabel = uilabel(app.QuantumMemristorUI);
            app.CalcLabel.HorizontalAlignment = 'center';
            app.CalcLabel.VerticalAlignment = 'center';
            app.CalcLabel.Position = [0 0 1280 720];
            app.CalcLabel.Text = 'PLEASE WAIT...';
            app.CalcLabel.FontWeight = 'bold';
            
            app.DisableCalc();
            app.EnableCSs();
        end
        
        function param = GetParam(app)
            param(1) = app.kbEditField.Value;
            param(2) = app.qEditField.Value;
            param(3) = app.a0EditField.Value;
            param(4) = app.EagEditField.Value;
            param(5) = app.EarEditField.Value;
            param(6) = app.eLEditField.Value;
            param(7) = app.gap_minEditField.Value;
            param(8) = app.gap_maxEditField.Value;
            param(9) = app.I0EditField.Value;
            param(10) = app.g0EditField.Value;
            param(11) = app.V0EditField.Value;
            param(12) = app.Ve10EditField.Value;
            param(13) = app.gamma0EditField.Value;
            param(14) = app.g1EditField.Value;
            param(15) = app.betaEditField.Value;
            param(16) = app.CthEditField.Value;
            param(17) = app.Tau_thEditField.Value;
            param(18) = app.T0EditField.Value;
            param(19) = app.AmplitudeEditField.Value;
            param(20) = app.WidthEditField.Value;
            param(21) = app.SimulationTimeStepEditField.Value;
        end
    end


    methods (Access = private)
        
        function disp_crossbar(app)
            [row,col,z]=size(app.R);
%              Neurons=row*ones(1,z+1);
            %%%%% Input (x,y) coords %%%%%
%             xdataN = zeros(1,sum(Neurons));
%             ydataN = zeros(1,sum(Neurons));
            WperL = 2*row*row*ones(1,z);
%             WperL = Neurons(1:end-1).*Neurons(2:end);
            xdataW = zeros(1,z*row*col);
            ydataW = zeros(1,z*row*col);
            
%             xdataP = zeros(1,sum(Neurons(2:end-1)));
%             ydataP = zeros(1,sum(Neurons(2:end-1)));
            
%             for i = 1:length(Neurons)
%                 xdataN(sum(Neurons(1:i-1))+(1:Neurons(i))) = 7*(i-1)+sum(Neurons(2:i))*ones(1,Neurons(i));
%                 ydataN(sum(Neurons(1:i-1))+(1:Neurons(i))) = -(0:Neurons(i)-1) + max(Neurons);
%             end
            
%             for i = 1:length(Neurons)-1
            for i = 1:z
%                 [tempX,tempY] = meshgrid(7*(i-1)+sum(Neurons(2:i))+(2:Neurons(i+1)+1),-(0:Neurons(i)-1) + max(Neurons));
                [tempX,tempY] = meshgrid((i-1)*(col+7)+(1:col),(row+1)-(1:row));
                xdataW((i-1)*row*col+(1:row*col)) = tempX(:);
                ydataW((i-1)*row*col+(1:row*col)) = tempY(:);
%                 xdataW(sum(WperL(1:i-1))+(1:WperL(i))) = tempX(:);
%                 ydataW(sum(WperL(1:i-1))+(1:WperL(i))) = tempY(:);
%                 if length(tempY(:,1))==2 %%% Avoid line plot bug when we have 2 rows
%                     plot(app.NetDisp,[tempX(1) tempX(end)],[tempY(1,1) tempY(1,1)],'k',...
%                         [tempX(1) tempX(end)],[tempY(2,1) tempY(2,1)],'LineWidth',1);
%                 else
%                     plot(app.NetDisp,[tempX(1) tempX(end)],[tempY(:,1) tempY(:,1)],'k','LineWidth',1);
%                 end
                hold(app.Crossbar,'on');
%                 memristor_size=15000/xdataW(end);
                x1=tempX(1,1)-1;
                x2=tempX(1,end)+1;
                y1=tempY(1,1)+0.2;
                y2=tempY(end,1)-0.2;
                square_x = [x1,x2,x2,x1,x1];
                square_y = [y1,y1,y2,y2,y1];
                plot(app.Crossbar,square_x, square_y, 'color', [.5 .5 .5], 'LineWidth', 3);
                text(app.Crossbar,(x1+x2)/2,y1+0.1*app.maxQB,['Step ', num2str(i)],'HorizontalAlignment','center','FontWeight','bold','color',[.5 .5 .5],'FontSize',16*(1-app.maxCS*.025));
%                 if length(tempX(1,:))==2 %%% Avoid line plot bug when we have 2 cols
%                     plot(app.NetDisp,[tempX(1,1) tempX(1,1)],[tempY(1,1) tempY(end,1)-1],'k','LineWidth',1);
%                     plot(app.NetDisp,[tempX(1,2) tempX(1,2)],[tempY(1,1) tempY(end,1)-1],'k','LineWidth',1);
%                 else
%                     plot(app.NetDisp,[tempX(1,:)' tempX(1,:)'],[tempY(1,1) tempY(end,1)-1],'k','LineWidth',1);
%                 end
                
%                 for j = 1:size(tempX,2)
%                     if i<length(Neurons)-1
%                         text(app.NetDisp,tempX(1,j),tempY(end,end)-1.2,['HN' num2str(i) '\_' num2str(j)],'FontSize',8,'Rotation',-90);
%                     else
%                         text(app.NetDisp,tempX(1,j),tempY(end,end)-1.2,['OUT\_' num2str(j)],'FontSize',8,'Rotation',-90);
%                     end
%                 end
            end
            hold(app.Crossbar,'on');
            
            
%             for i = 2:length(Neurons)-1
%                 xdataP(sum(Neurons(2:i-1))+(1:Neurons(i))) = 7*i+sum(Neurons(2:i+1))*ones(1,Neurons(i))-4;
%                 ydataP(sum(Neurons(2:i-1))+(1:Neurons(i))) = -(0:Neurons(i)-1) + max(Neurons);
%             end
%             
%             for i = 1:Neurons(1)
%                 text(app.NetDisp,xdataN(i)-0.75,ydataN(i)+0.25,['IN\_' num2str(i)],'FontSize',8);
%             end
%             
%             for i = 2:length(Neurons)-1
%                 for j = 1:Neurons(i)
%                     ii = sum(Neurons(1:i-1))+j;
%                     text(app.NetDisp,xdataN(ii)-0.75,ydataN(ii)+0.25,['HN' num2str(i-1) '\_' num2str(j)],'FontSize',8);
%                 end
%             end
%             
%             for i = 1:Neurons(end)
%                 ii = sum(Neurons(1:end-1))+i;
%                 text(app.NetDisp,xdataN(ii)-0.75,ydataN(ii)+0.25,['OUT\_' num2str(i)],'FontSize',8);
%             end
%             
%             for i = 1:length(Neurons)-2
%                 for j = 1:Neurons(i+1)
%                     ii = sum(Neurons(2:i))+j;
%                     text(app.NetDisp,xdataP(ii)-0.75,ydataP(ii)+0.25,['Prog' num2str(i+1) '\_' num2str(j)],'FontSize',8);
%                 end
%             end
            
%             app.Ndots = scatter(app.NetDisp,xdataN,ydataN,'ro');
            hold(app.Crossbar,'on');
            c = app.R(:);
            app.Wdots = scatter(app.Crossbar,xdataW,ydataW,20000/xdataW(end),c,'filled','square');
            %             for i = 1:length(app.Wdots)
            %             app.Wdots(i).MarkerFaceColor = [0 .7 .7];
            %             end
%             app.Pdots = scatter(app.NetDisp,xdataP,ydataP,'ko');
            dcm_obj = datacursormode(app.QuantumMemristorUI);
            set(dcm_obj,'UpdateFcn',@(hObject, event_obj) myupdatefcn(hObject, event_obj, xdataW, ydataW, c) );
            button = 1;
            hold(app.Crossbar,'off');
            
            app.Crossbar.YLim = [0.5 row+.5];
            app.Crossbar.XLim = [0 xdataW(end)+1];
            c=colorbar(app.Crossbar,'southoutside');
            c.AxisLocation='in';
            c.FontSize=11;
            
%             set(app.Crossbar,'ColorScale','log')
%             R_max=max(max(max(app.R)));
%             R_min=min(min(min(app.R)));
%             c.Ticks=[R_min R_max];

%             R_max=max(max(max(app.R)));
%             R_min=min(min(min(app.R)));
%             c=colorbar(app.Colorbaraki,'southoutside');
%              caxis(app.Colorbaraki,[R_min R_max]);
%              c.FontSize=10;
            
            function output_txt = myupdatefcn(~, event_obj, X, Y, Z)
                % ~            Currently not used (empty)
                % event_obj    Object containing event data structure
                % output_txt   Data cursor text
                %find the closest data point to the cursor
                cursor_pos = event_obj.Position;
                dist2_to_dots = (X - cursor_pos(1)).^2 + (Y - cursor_pos(2)).^2;
                [~, item_idx] = min(dist2_to_dots);
                output_txt = {sprintf('R: %g', Z(item_idx)) };
            end
        end
        
        function disp_write(app)
            cla(app.WriteVoltagesPlot);
            cla(app.SelectorVoltagesPlot);
            
            value = str2num(app.SelectComputationalSteptoViewDropDown.Value(6));
            marksman=['-',':'];
            hold(app.WriteVoltagesPlot,'on');
            
            line_width1=linspace(3,1,size(app.V_write,1));
            line_width2=linspace(3,1,size(app.VT_plot,1));
            for i=1:size(app.V_write,1)
                leg(i) = "Row " + num2str(i);
                  plot(app.WriteVoltagesPlot,app.time_plot,app.V_write(i,:,value), marksman(1+mod(i,2)), 'LineWidth', line_width1(i));
%                 plot(app.WriteVoltagesPlot,app.time_plot,app.V_write(i,:,value), marksman(1+mod(i,2)), 'LineWidth', 0.3+(1+size(app.V_write,1)-i)*.15);
            end
        
            app.WriteVoltagesPlot.YLim = [-3 5];
            app.WriteVoltagesPlot.XLim = [0 app.time_plot(end)];
            legend(app.WriteVoltagesPlot, leg,'Orientation','horizontal', 'NumColumns', 12)
            hold(app.SelectorVoltagesPlot,'on');
            
            for i=1:size(app.VT_plot,1)
                leg(i) = "Col " + num2str(i);
                  plot(app.SelectorVoltagesPlot,app.time_plot,app.VT_plot(i,:,value), marksman(1+mod(i,2)), 'LineWidth', line_width2(i));
%                 plot(app.SelectorVoltagesPlot,app.time_plot,app.VT_plot(i,:,value), marksman(1+mod(i,2)), 'LineWidth', 0.2+(1+size(app.VT_plot,1)-i)*.1);
            end
            
            app.SelectorVoltagesPlot.YLim = [0 8];
            app.SelectorVoltagesPlot.XLim = [0 app.time_plot(end)];
            legend(app.SelectorVoltagesPlot, leg,'Orientation','horizontal', 'NumColumns', 12)
            hold(app.WriteVoltagesPlot,'off');
            hold(app.SelectorVoltagesPlot,'off');   
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.create();
            app.DisableCalc();
            
            app.EnableQBs();
            app.EnableGenerate();
        end

        % Value changed function: NoofQubits
        function NoofQubitsValueChanged(app, event)
            value = app.NoofQubits.Value;
            
            app.NoofQubits.Value = round(value, 0);
            app.maxQB = round(value, 0);
            
            app.EnableQBs();
        end

        % Value changed function: NoofComputationalSteps
        function NoofComputationalStepsValueChanged(app, event)
            value = app.NoofComputationalSteps.Value;
            
            app.NoofComputationalSteps.Value = round(value, 0);
            app.maxCS = round(value, 0);
            
            app.EnableCSs();
        end

        % Button pushed function: GENERATE
        function GENERATEButtonPushed(app, event)
            app.EnableCalc();
            pause(0);
            app.GenerateButton();
            app.EnableRun();
        end

        % Button pushed function: RUN
        function RUNButtonPushed(app, event)
            app.EnableCalc();
            pause(0);
            app.RunButton();
        end

        % Value changed function: ViewgateselectionasSwitch
        function ViewgateselectionasSwitchValueChanged(app, event)
            app.EnableCalc();
            pause(0);
            app.GateView();
        end

        % Value changed function: AmplitudeEditField, CthEditField, 
        % ...and 19 other components
        function kbEditFieldValueChanged(app, event)
            app.EnableGenerate();
        end

        % Value changed function: SelectComputationalSteptoViewDropDown
        function SelectComputationalSteptoViewDropDownValueChanged(app, event)
            disp_write(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab.Title;
            dcm_obj = datacursormode(app.QuantumMemristorUI);
            
            if (strcmp(selectedTab, 'Memristor Crossbar'))
                dcm_obj.Enable = 'on';
            else
                dcm_obj.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantumMemristorUI = uifigure('Visible', 'off');
            app.QuantumMemristorUI.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.QuantumMemristorUI);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.QuantumCircuitDesignTab = uitab(app.TabGroup);
            app.QuantumCircuitDesignTab.Title = 'Quantum Circuit Design';
            app.GENERATE = uibutton(app.QuantumCircuitDesignTab, 'push');
            app.GENERATE.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATE.Tag = 'GENERATE';
            app.GENERATE.Text = 'GENERATE';
            app.NoofQubits = uislider(app.QuantumCircuitDesignTab);
            app.NoofQubits.Limits = [1 5];
            app.NoofQubits.MajorTicks = [1 2 3 4 5];
            app.NoofQubits.ValueChangedFcn = createCallbackFcn(app, @NoofQubitsValueChanged, true);
            app.NoofQubits.MinorTicks = [];
            app.NoofQubits.Value = 1;
            app.NoofComputationalSteps = uislider(app.QuantumCircuitDesignTab);
            app.NoofComputationalSteps.Limits = [1 10];
            app.NoofComputationalSteps.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NoofComputationalSteps.ValueChangedFcn = createCallbackFcn(app, @NoofComputationalStepsValueChanged, true);
            app.NoofComputationalSteps.MinorTicks = [];
            app.NoofComputationalSteps.Value = 1;
            app.NoofQubitsSliderLabel = uilabel(app.QuantumCircuitDesignTab);
            app.NoofQubitsSliderLabel.Text = 'Number of Qubits:';
            app.NoofComputationalStepsSliderLabel = uilabel(app.QuantumCircuitDesignTab);
            app.NoofComputationalStepsSliderLabel.Text = 'Number of Computational Steps:';
            app.ViewgateselectionasSwitchLabel = uilabel(app.QuantumCircuitDesignTab);
            app.ViewgateselectionasSwitchLabel.Text = 'View gate selection as...';
            app.ViewgateselectionasSwitch = uiswitch(app.QuantumCircuitDesignTab, 'rocker');
            app.ViewgateselectionasSwitch.Items = {'Dropdown', 'Knob'};
            app.ViewgateselectionasSwitch.ValueChangedFcn = createCallbackFcn(app, @ViewgateselectionasSwitchValueChanged, true);
            app.ViewgateselectionasSwitch.Value = 'Dropdown';
            app.MemristorCrossbarTab = uitab(app.TabGroup);
            app.MemristorCrossbarTab.Title = 'Memristor Crossbar';
            app.Crossbar = uiaxes(app.MemristorCrossbarTab);
            app.Crossbar.XTick = [];
            app.Crossbar.YTick = [];
            app.WriteVoltageFiguresTab = uitab(app.TabGroup);
            app.WriteVoltageFiguresTab.Title = 'Write Voltage Figures';
            app.WriteVoltagesPlot = uiaxes(app.WriteVoltageFiguresTab);
            title(app.WriteVoltagesPlot, 'Write Plot')
            xlabel(app.WriteVoltagesPlot, 'Time (s)')
            ylabel(app.WriteVoltagesPlot, 'Voltage (V)')
            app.SelectorVoltagesPlot = uiaxes(app.WriteVoltageFiguresTab);
            title(app.SelectorVoltagesPlot, 'Selector Plot')
            xlabel(app.SelectorVoltagesPlot, 'Time (s)')
            ylabel(app.SelectorVoltagesPlot, 'Voltage (V)')
            app.SelectComputationalSteptoViewDropDownLabel = uilabel(app.WriteVoltageFiguresTab);
            app.SelectComputationalSteptoViewDropDownLabel.Text = 'Select Computational Step to View:';
            app.SelectComputationalSteptoViewDropDown = uidropdown(app.WriteVoltageFiguresTab);
            app.SelectComputationalSteptoViewDropDown.Items = {'Step 1', 'Step 2', 'Step 3', 'Step 4', 'Step 5', 'Step 6', 'Step 7', 'Step 8', 'Step 9', 'Step 10', 'Step 11', 'Step 12'};
            app.SelectComputationalSteptoViewDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectComputationalSteptoViewDropDownValueChanged, true);
            app.SelectComputationalSteptoViewDropDown.Value = 'Step 1';
            app.QuantumComputationsTab = uitab(app.TabGroup);
            app.QuantumComputationsTab.Title = 'Quantum Computations';
            app.PLOT = uiaxes(app.QuantumComputationsTab);
            xlabel(app.PLOT, 'Qubit Base States')
            ylabel(app.PLOT, 'Output Coefficients')
            app.PLOT.YLim = [-1 1];
            app.PLOT.XTick = [0.11 0.22 0.33 0.44 0.56 0.67 0.78 0.89];
            app.PLOT.XTickLabel = {'|000>'; '|001>'; '|010>'; '|011>'; '|100>'; '|101>'; '|110>'; '|111>'};
            app.PLOT.Tag = 'PLOT';
            app.RUN = uibutton(app.QuantumComputationsTab, 'push');
            app.RUN.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUN.Tag = 'RUN';
            app.RUN.Text = 'RUN';
            app.MemristorSettingsTab = uitab(app.TabGroup);
            app.MemristorSettingsTab.Title = 'Memristor Settings';
            app.kbEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.kbEditFieldLabel.Text = 'kb';
            app.kbEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.kbEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.kbEditField.Value = 1.38e-23;
            app.ModelParametersLabel = uilabel(app.MemristorSettingsTab);
            app.ModelParametersLabel.Text = 'Model Parameters';
            app.PulseParametersLabel = uilabel(app.MemristorSettingsTab);
            app.PulseParametersLabel.Text = 'Pulse Parameters';
            app.qEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.qEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.qEditField.Value = 1.6e-19;
            app.a0EditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.a0EditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.a0EditField.Value = 2.5e-10;
            app.EagEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.EagEditFieldLabel.Text = 'Eag';
            app.EagEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.EagEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.EagEditField.Value = 1.501;
            app.EarEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.EarEditFieldLabel.Text = 'Ear';
            app.EarEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.EarEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.EarEditField.Value = 1.5;
            app.eLEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.eLEditFieldLabel.Text = 'eL';
            app.eLEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.eLEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.eLEditField.Value = 5e-09;
            app.gap_minEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.gap_minEditFieldLabel.Text = 'gap_min';
            app.gap_minEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.gap_minEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.gap_minEditField.Value = 1e-10;
            app.gap_maxEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.gap_maxEditFieldLabel.Text = 'gap_max';
            app.gap_maxEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.gap_maxEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.gap_maxEditField.Value = 1.7e-09;
            app.I0EditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.I0EditFieldLabel.Text = 'I0';
            app.I0EditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.I0EditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.I0EditField.Value = 6.14e-05;
            app.g0EditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.g0EditFieldLabel.Text = 'g0';
            app.g0EditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.g0EditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.g0EditField.Value = 2.75e-10;
            app.V0EditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.V0EditFieldLabel.Text = 'V0';
            app.V0EditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.V0EditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.V0EditField.Value = 0.43;
            app.Ve10EditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.Ve10EditFieldLabel.Text = 'Ve10';
            app.Ve10EditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.Ve10EditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.Ve10EditField.Value = 150;
            app.gamma0EditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.gamma0EditFieldLabel.Text = 'gamma0';
            app.gamma0EditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.gamma0EditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.gamma0EditField.Value = 16.5;
            app.g1EditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.g1EditFieldLabel.Text = 'g1';
            app.g1EditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.g1EditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.g1EditField.Value = 1e-09;
            app.betaEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.betaEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.betaEditField.Value = 1.25;
            app.CthEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.CthEditFieldLabel.Text = 'Cth';
            app.CthEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.CthEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.CthEditField.Value = 3.18e-16;
            app.Tau_thEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.Tau_thEditFieldLabel.Text = 'Tau_th';
            app.Tau_thEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.Tau_thEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.Tau_thEditField.Value = 2.3e-10;
            app.T0EditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.T0EditFieldLabel.Text = 'T0';
            app.T0EditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.T0EditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.T0EditField.Value = 298;
            app.AmplitudeEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = 2.5;
            app.WidthEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.WidthEditField.Value = 1e-08;
            app.SimulationTimeStepEditFieldLabel = uilabel(app.MemristorSettingsTab);
            app.SimulationTimeStepEditFieldLabel.Text = 'Simulation Time Step';
            app.SimulationTimeStepEditField = uieditfield(app.MemristorSettingsTab, 'numeric');
            app.SimulationTimeStepEditField.ValueChangedFcn = createCallbackFcn(app, @kbEditFieldValueChanged, true);
            app.SimulationTimeStepEditField.Value = 1e-11;
            % Show the figure after all components are created
            app.QuantumMemristorUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuantumMemristorDynamicFinal3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantumMemristorUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantumMemristorUI)
        end
    end
end